classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ProcessedImagePanel       matlab.ui.container.Panel
        FourierButton_2           matlab.ui.control.Button
        SaveImgButton             matlab.ui.control.Button
        HistButton_2              matlab.ui.control.Button
        ProcessedImage            matlab.ui.control.UIAxes
        OriginalImagePanel        matlab.ui.container.Panel
        FourierButton             matlab.ui.control.Button
        HistButton                matlab.ui.control.Button
        OriginalImage             matlab.ui.control.UIAxes
        OptionsPanel              matlab.ui.container.Panel
        nEditField                matlab.ui.control.NumericEditField
        nEditFieldLabel           matlab.ui.control.Label
        d0EditField               matlab.ui.control.NumericEditField
        d0EditFieldLabel          matlab.ui.control.Label
        FilenamelabelLabel        matlab.ui.control.Label
        dEditField                matlab.ui.control.NumericEditField
        dEditFieldLabel           matlab.ui.control.Label
        KernelSizeEditField       matlab.ui.control.NumericEditField
        KernelSizeEditFieldLabel  matlab.ui.control.Label
        KernelEditField           matlab.ui.control.EditField
        KernelEditFieldLabel      matlab.ui.control.Label
        ProcessButton             matlab.ui.control.Button
        ChooseImageButton         matlab.ui.control.Button
        ModeDropDown              matlab.ui.control.DropDown
        ModeDropDownLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global img;
            global newImg;
            img = -1;
            ref = -1;
            newImg = -1;
            app.KernelSizeEditField.Visible = 'off';
            app.KernelSizeEditFieldLabel.Visible = 'off';
            app.dEditField.Visible = 'off';
            app.dEditFieldLabel.Visible = 'off';
            app.FilenamelabelLabel.Text = '';
            app.d0EditField.Visible = 'off';
            app.d0EditFieldLabel.Visible = 'off';
            app.nEditField.Visible = 'off';
            app.nEditFieldLabel.Visible = 'off';
        end

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
           global img;
           [filename, pathname] = uigetfile('*.*', 'pick an image');
           app.FilenamelabelLabel.Text = filename;
           filename = strcat(pathname, filename);
           img = imread(filename);
           imshow(img, 'Parent', app.OriginalImage);
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            app.dEditField.Visible = 'off';
            app.dEditFieldLabel.Visible = 'off';
            app.d0EditField.Visible = 'off';
            app.d0EditFieldLabel.Visible = 'off';
            app.nEditField.Visible = 'off';
            app.nEditFieldLabel.Visible = 'off';
            if (value == "Alpha trimmed")
                app.KernelEditField.Visible = 'off';
                app.KernelEditFieldLabel.Visible = 'off';
                app.KernelSizeEditField.Visible = 'on';
                app.KernelSizeEditFieldLabel.Visible = 'on';
                app.dEditField.Visible = 'on';
                app.dEditFieldLabel.Visible = 'on';
            elseif (value == "Convolution" || value == "Convolution Matlab")
                app.KernelEditField.Visible = 'on';
                app.KernelEditFieldLabel.Visible = 'on';
                app.KernelSizeEditField.Visible = 'off';
                app.KernelSizeEditFieldLabel.Visible = 'off';
            elseif (value == "ILPF" || value == "GLPF" || value == "BLPF" || value == "IHPF" || value == "GHPF" || value == "BHPF")
                app.d0EditField.Visible = 'on';
                app.d0EditFieldLabel.Visible = 'on';
                app.nEditField.Visible = 'on';
                app.nEditFieldLabel.Visible = 'on';
            else
                app.KernelEditField.Visible = 'off';
                app.KernelEditFieldLabel.Visible = 'off';
                app.KernelSizeEditField.Visible = 'off';
                app.KernelSizeEditFieldLabel.Visible = 'off';
                app.d0EditField.Visible = 'off';
                app.d0EditFieldLabel.Visible = 'off';
                app.nEditField.Visible = 'off';
                app.nEditFieldLabel.Visible = 'off';
            end
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            global newImg;
            global img;
            kernel = app.KernelEditField.Value;
            kernelSize = app.KernelSizeEditField.Value;
            d = app.dEditField.Value;
            d0 = app.d0EditField.Value;
            n = app.nEditField.Value;
            %change kernel to actual matrix
            kernel = stringToMatrix(kernel);
            disp(kernel);
            value = app.ModeDropDown.Value;
            if value == "Convolution"
                newImg = ImProcTools.convolution(img, kernel, "fillzero");
            elseif value == "Convolution Matlab"
                r = img(:,:,1);
                g = img(:,:,2);
                b = img(:,:,3);
                convolvedRed = conv2(r, kernel, 'same');
                convolvedGreen = conv2(g, kernel, 'same');
                convolvedBlue = conv2(b, kernel, 'same');
                newImg = cat(3, convolvedRed, convolvedGreen, convolvedBlue);
                
            elseif value == "ILPF"
                newImg = Filter.ipf(img, d0, 0);
            elseif value == "GLPF"
                newImg = Filter.gpf(img, d0, 0);
            elseif value == "BLPF"
                newImg = Filter.bpf(img, d0, n, 0);
            elseif value == "IHPF"
                newImg = Filter.ipf(img, d0, 1);
            elseif value == "GHPF"
                newImg = Filter.gpf(img, d0, 1);
            elseif value == "BHPF"
                newImg = Filter.bpf(img, d0, n, 1);
            elseif value == "Max filter"
                newImg = Filter.spatialFilter(img, "fillzero", "max", kernelSize, d);
            elseif value == "Min filter"
                newImg = Filter.spatialFilter(img, "fillzero", "min", kernelSize, d);
            elseif value == "Median filter"
                newImg = Filter.spatialFilter(img, "fillzero", "median", kernelSize, d);
            elseif value == "Arith mean filter"
                newImg = Filter.spatialFilter(img, "fillzero", "arithmetic_mean", kernelSize, d);
            elseif value == "Geo mean filter"
                newImg = Filter.spatialFilter(img, "fillzero", "geo_mean", kernelSize, d);
            elseif value == "Harmonic mean filter"
                newImg = Filter.spatialFilter(img, "fillzero", "harmonic_mean", kernelSize, d);
            elseif value == "Midpoint filter"
                newImg = Filter.spatialFilter(img, "fillzero", "midpoint", kernelSize, d);
            elseif value == "Alpha trimmed"
                newImg = Filter.spatialFilter(img, "fillzero", "alpha_trimmed_mean", kernelSize, d);
            elseif value == "SaltPepper"
                newImg = imnoise(img, 'salt & pepper', 0.1);
            end
            imshow(newImg, 'Parent', app.ProcessedImage);
        end

        % Callback function
        function ImageReferenceButtonPushed(app, event)
           global ref;
           [filename, pathname] = uigetfile('*.*', 'pick an image');
           filename = strcat(pathname, filename);
           ref = imread(filename);
           app.FilenameLabel.Text = strcat("Filename : ", filename);
           
        end

        % Clicked callback: ModeDropDown
        function ModeDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Button pushed function: HistButton
        function HistButtonPushed(app, event)
            global img;
            histimg = ImProcTools.getHistImg(img);
        end

        % Button pushed function: FourierButton
        function FourierButtonPushed(app, event)
            global img;
            fourier = ImProcTools.showFourierImg(img, 1);
        end

        % Button pushed function: HistButton_2
        function HistButton_2Pushed(app, event)
            global newImg;
            histimg = ImProcTools.getHistImg(newImg);
        end

        % Button pushed function: FourierButton_2
        function FourierButton_2Pushed(app, event)
            global newImg;
            fourier = ImProcTools.showFourierImg(newImg, 1);
        end

        % Button pushed function: SaveImgButton
        function SaveImgButtonPushed(app, event)
            global newImg;
            imwrite(newImg, "result.png");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.ModeDropDownLabel = uilabel(app.OptionsPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.OptionsPanel);
            app.ModeDropDown.Items = {'Convolution', 'Convolution Matlab', 'ILPF', 'GLPF', 'BLPF', 'IHPF', 'GHPF', 'BHPF', 'Mean filter', 'Max filter', 'Min filter', 'Median filter', 'Arith mean filter', 'Geo mean filter', 'Harmonic mean filter', 'Midpoint filter', 'Alpha trimmed', 'SaltPepper'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.ClickedFcn = createCallbackFcn(app, @ModeDropDownClicked, true);
            app.ModeDropDown.Value = 'Convolution';
            app.ChooseImageButton = uibutton(app.OptionsPanel, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.ProcessButton = uibutton(app.OptionsPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.KernelEditFieldLabel = uilabel(app.OptionsPanel);
            app.KernelEditFieldLabel.Text = 'Kernel';
            app.KernelEditField = uieditfield(app.OptionsPanel, 'text');
            app.KernelEditField.Value = '0 -1 0 ; -1 4 -1 ; 0 -1 0';
            app.KernelSizeEditFieldLabel = uilabel(app.OptionsPanel);
            app.KernelSizeEditFieldLabel.Text = 'Kernel Size';
            app.KernelSizeEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.KernelSizeEditField.Value = 3;
            app.dEditFieldLabel = uilabel(app.OptionsPanel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.dEditField.Value = 1;
            app.FilenamelabelLabel = uilabel(app.OptionsPanel);
            app.FilenamelabelLabel.Text = 'Filename label';
            app.d0EditFieldLabel = uilabel(app.OptionsPanel);
            app.d0EditFieldLabel.Text = 'd0';
            app.d0EditField = uieditfield(app.OptionsPanel, 'numeric');
            app.d0EditField.Value = 1;
            app.nEditFieldLabel = uilabel(app.OptionsPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.nEditField.Value = 1;
            app.OriginalImagePanel = uipanel(app.UIFigure);
            app.OriginalImagePanel.Title = 'Original Image';
            app.OriginalImage = uiaxes(app.OriginalImagePanel);
            title(app.OriginalImage, 'Image')
            xlabel(app.OriginalImage, 'X')
            ylabel(app.OriginalImage, 'Y')
            zlabel(app.OriginalImage, 'Z')
            app.HistButton = uibutton(app.OriginalImagePanel, 'push');
            app.HistButton.ButtonPushedFcn = createCallbackFcn(app, @HistButtonPushed, true);
            app.HistButton.Text = 'Hist';
            app.FourierButton = uibutton(app.OriginalImagePanel, 'push');
            app.FourierButton.ButtonPushedFcn = createCallbackFcn(app, @FourierButtonPushed, true);
            app.FourierButton.Text = 'Fourier';
            app.ProcessedImagePanel = uipanel(app.UIFigure);
            app.ProcessedImagePanel.Title = 'Processed Image';
            app.ProcessedImage = uiaxes(app.ProcessedImagePanel);
            title(app.ProcessedImage, 'Image')
            xlabel(app.ProcessedImage, 'X')
            ylabel(app.ProcessedImage, 'Y')
            zlabel(app.ProcessedImage, 'Z')
            app.HistButton_2 = uibutton(app.ProcessedImagePanel, 'push');
            app.HistButton_2.ButtonPushedFcn = createCallbackFcn(app, @HistButton_2Pushed, true);
            app.HistButton_2.Text = 'Hist';
            app.SaveImgButton = uibutton(app.ProcessedImagePanel, 'push');
            app.SaveImgButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImgButtonPushed, true);
            app.SaveImgButton.Text = 'Save Img';
            app.FourierButton_2 = uibutton(app.ProcessedImagePanel, 'push');
            app.FourierButton_2.ButtonPushedFcn = createCallbackFcn(app, @FourierButton_2Pushed, true);
            app.FourierButton_2.Text = 'Fourier';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end